classdef correctEventApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EventstodeleteframeEditField  matlab.ui.control.EditField
        EventstodeleteframeEditFieldLabel  matlab.ui.control.Label
        NeweventsframeseparatedbyspaceEditField  matlab.ui.control.EditField
        NeweventsframeseparatedbyspaceLabel  matlab.ui.control.Label
        SelectlabelandaddgaiteventsLabel  matlab.ui.control.Label
        VariableDropDown              matlab.ui.control.DropDown
        VariableDropDownLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FinishButtonPushed(app, event)
            
        end

        % Callback function
        function NeweventsframeEditFieldValueChanged(app, event)


        end

        % Value changed function: NeweventsframeseparatedbyspaceEditField
        function NeweventsframeseparatedbyspaceEditFieldValueChanged(app, event)
            value = app.NeweventsframeseparatedbyspaceEditField.Value;
            newEvents = str2num(value);

            LS = evalin('base','Left_Strike');
            LO = evalin('base','Left_Off');
            RS = evalin('base','Right_Strike');
            RO = evalin('base','Right_Off');
            if size(RO,1)>1
                sizeCat = 1;
            else
                sizeCat = 2;
            end
            insert = @(a, x, n)cat(sizeCat,  x(1:n), a, x(n+1:end));
            if (strcmp(app.VariableDropDown.Value, 'Left_Strike'))
                for numEvent = 1:length(newEvents)
                    % check whether this event has already been created
                    if not(isempty(intersect(newEvents(numEvent),LS)))
                        % if yes, do nothing
                    else
                        % if no, find the position of the new event in the events vector
                        index = find(newEvents(numEvent) < LS);
                        if isempty(index) % the new event is the very last one
                            position = length(LS) +1;
                            LS(position) = newEvents(numEvent);
                        else
                            position = index(1)-1;
                            if position == 0 % add event in the first position
                                LS = [newEvents(numEvent); LS];
                            else
                                LS = insert(newEvents(numEvent), LS, position);
                            end
                        end
                    end
                end
                assignin('base','Left_Strike',LS);
            end


            if (strcmp(app.VariableDropDown.Value, 'Left_Off'))

                for numEvent = 1:length(newEvents)
                    % check whether this event has already been created
                    if not(isempty(intersect(newEvents(numEvent),LO)))
                        % if yes, do nothing
                    else
                        % find the position of the new event in the events vector
                        index = find(newEvents(numEvent) < LO);
                        if isempty(index) % the new event is the very last one
                            position = length(LO) +1;
                            LO(position) = newEvents(numEvent);
                        else
                            position = index(1)-1;
                            if position == 0
                                LO = [newEvents(numEvent); LO];
                            else
                                LO = insert(newEvents(numEvent), LO, position);
                            end
                        end
                    end
                end
                assignin('base','Left_Off',LO);
            end

            if (strcmp(app.VariableDropDown.Value, 'Right_Strike'))

                 for numEvent = 1:length(newEvents)
                     % check whether this event has already been created
                     if not(isempty(intersect(newEvents(numEvent),RS)))
                         % if yes, do nothing
                     else
                         % find the position of the new event in the events vector
                         index = find(newEvents(numEvent) < RS);
                         if isempty(index) % the new event is the very last one
                             position = length(RS) +1;
                             RS(position) = newEvents(numEvent);
                         else
                             position = index(1)-1;
                             if position == 0
                                 RS = [newEvents(numEvent); RS];
                             else
                                 RS = insert(newEvents(numEvent), RS, position);
                             end
                         end
                     end
                 end
                assignin('base','Right_Strike',RS);
            end


            if (strcmp(app.VariableDropDown.Value, 'Right_Off'))

                for numEvent = 1:length(newEvents)
                    % check whether this event has already been created
                    if not(isempty(intersect(newEvents(numEvent),RO)))
                        % if yes, do nothing
                    else
                        % find the position of the new event in the events vector
                        index = find(newEvents(numEvent) < RO);
                        if isempty(index) % the new event is the very last one
                            position = length(RO) +1;
                            RO(position) = newEvents(numEvent);
                        else
                            position = index(1)-1;
                            if position == 0
                                RO = [newEvents(numEvent); RO];
                            else
                                RO = insert(newEvents(numEvent), RO, position);
                            end
                        end
                    end
                end
                assignin('base','Right_Off',RO);
            end

            app.NeweventsframeseparatedbyspaceEditField.Value = '';
        end

        % Value changed function: EventstodeleteframeEditField
        function EventstodeleteframeEditFieldValueChanged(app, event)
            value = app.EventstodeleteframeEditField.Value;
            wrongEvents = str2num(value);

            LS = evalin('base','Left_Strike');
            LO = evalin('base','Left_Off');
            RS = evalin('base','Right_Strike');
            RO = evalin('base','Right_Off');
            if (strcmp(app.VariableDropDown.Value, 'Left_Strike'))
                [~,~, ib] =intersect(wrongEvents,LS);
                LS(ib) = [];  
                assignin('base','Left_Strike',LS);
            end


            if (strcmp(app.VariableDropDown.Value, 'Left_Off'))
                [~,~, ib] =intersect(wrongEvents,LO);
                LO(ib) = [];
                assignin('base','Left_Off',LO);
            end

            if (strcmp(app.VariableDropDown.Value, 'Right_Strike'))
                [~,~, ib] =intersect(wrongEvents,RS);
                RS(ib) = [];
                assignin('base','Right_Strike',RS);
            end


            if (strcmp(app.VariableDropDown.Value, 'Right_Off'))
                [~,~, ib] =intersect(wrongEvents,RO);
                RO(ib) = [];
                assignin('base','Right_Off',RO);
            end
            app.EventstodeleteframeEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VariableDropDownLabel = uilabel(app.UIFigure);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.UIFigure);
            app.VariableDropDown.Items = {'Left_Strike', 'Left_Off', 'Right_Strike', 'Right_Off'};
            app.VariableDropDown.Value = 'Left_Strike';
            app.SelectlabelandaddgaiteventsLabel = uilabel(app.UIFigure);
            app.SelectlabelandaddgaiteventsLabel.Text = 'Select label and add gait event(s)';
            app.NeweventsframeseparatedbyspaceLabel = uilabel(app.UIFigure);
            app.NeweventsframeseparatedbyspaceLabel.Text = {'New event(s) [frame]'; 'separated by space'};
            app.NeweventsframeseparatedbyspaceEditField = uieditfield(app.UIFigure, 'text');
            app.NeweventsframeseparatedbyspaceEditField.ValueChangedFcn = createCallbackFcn(app, @NeweventsframeseparatedbyspaceEditFieldValueChanged, true);
            app.EventstodeleteframeEditFieldLabel = uilabel(app.UIFigure);
            app.EventstodeleteframeEditFieldLabel.Text = 'Events to delete [frame]';
            app.EventstodeleteframeEditField = uieditfield(app.UIFigure, 'text');
            app.EventstodeleteframeEditField.ValueChangedFcn = createCallbackFcn(app, @EventstodeleteframeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correctEventApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end